classdef geologicalCycle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ENSGeologicalCycleUIFigure      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Introduction12Tab               matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        Button                          matlab.ui.control.Button
        RockCycleModelLabel             matlab.ui.control.Label
        Introduction22Tab               matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        AcronymsforparametersLabel      matlab.ui.control.Label
        params_Data                     matlab.ui.container.Tab
        Init_MantleEditFieldLabel       matlab.ui.control.Label
        Init_MantleEditField            matlab.ui.control.NumericEditField
        ResetSteadyStateButton          matlab.ui.control.Button
        RunandPlotButton                matlab.ui.control.Button
        InitialMassPgLabel_2            matlab.ui.control.Label
        InitialRatesPg10MyrLabel        matlab.ui.control.Label
        Init_Ocean_CrustEditFieldLabel  matlab.ui.control.Label
        Init_Ocean_CrustEditField       matlab.ui.control.NumericEditField
        Init_Cont_Ig_RxEditFieldLabel   matlab.ui.control.Label
        Init_Cont_Ig_RxEditField        matlab.ui.control.NumericEditField
        Init_SedEditFieldLabel          matlab.ui.control.Label
        Init_SedEditField               matlab.ui.control.NumericEditField
        Rate_OC_SubductionEditFieldLabel  matlab.ui.control.Label
        Rate_OC_SubductionEditField     matlab.ui.control.NumericEditField
        Rate_Arc_VolcanismEditFieldLabel  matlab.ui.control.Label
        Rate_Arc_VolcanismEditField     matlab.ui.control.NumericEditField
        Rate_CIR_WeathEditFieldLabel    matlab.ui.control.Label
        Rate_CIR_WeathEditField         matlab.ui.control.NumericEditField
        Rate_CIR_MetaEditFieldLabel     matlab.ui.control.Label
        Rate_CIR_MetaEditField          matlab.ui.control.NumericEditField
        Rate_LithificationEditFieldLabel  matlab.ui.control.Label
        Rate_LithificationEditField     matlab.ui.control.NumericEditField
        Rate_OC_ProductionEditFieldLabel  matlab.ui.control.Label
        Rate_OC_ProductionEditField     matlab.ui.control.NumericEditField
        Rate_SR_MetaEditFieldLabel      matlab.ui.control.Label
        Rate_SR_MetaEditField           matlab.ui.control.NumericEditField
        Rate_MR_WeathEditFieldLabel     matlab.ui.control.Label
        Rate_MR_WeathEditField          matlab.ui.control.NumericEditField
        Rate_MeltingEditFieldLabel      matlab.ui.control.Label
        Rate_MeltingEditField           matlab.ui.control.NumericEditField
        Rate_MR_SubductionEditFieldLabel  matlab.ui.control.Label
        Rate_MR_SubductionEditField     matlab.ui.control.NumericEditField
        Rate_SR_WeathEditFieldLabel     matlab.ui.control.Label
        Rate_SR_WeathEditField          matlab.ui.control.NumericEditField
        SimulationTime10MyrSliderLabel  matlab.ui.control.Label
        SimulationTime10MyrSlider       matlab.ui.control.Slider
        Init_Meta_RxEditFieldLabel      matlab.ui.control.Label
        Init_Meta_RxEditField           matlab.ui.control.NumericEditField
        Init_Sed_RxEditFieldLabel       matlab.ui.control.Label
        Init_Sed_RxEditField            matlab.ui.control.NumericEditField
        InitialParameters12Tab          matlab.ui.container.Tab
        MantleKnobLabel                 matlab.ui.control.Label
        MantleKnob                      matlab.ui.control.Knob
        IgneousRocksKnobLabel           matlab.ui.control.Label
        IgneousRocksKnob                matlab.ui.control.Knob
        MetamorphicRocksKnobLabel       matlab.ui.control.Label
        MetamorphicRocksKnob            matlab.ui.control.Knob
        SedimentaryRocksKnobLabel       matlab.ui.control.Label
        SedimentaryRocksKnob            matlab.ui.control.Knob
        OceanCrustKnobLabel             matlab.ui.control.Label
        OceanCrustKnob                  matlab.ui.control.Knob
        SedimentsKnobLabel              matlab.ui.control.Label
        SedimentsKnob                   matlab.ui.control.Knob
        InitialMassPgLabel              matlab.ui.control.Label
        OC_ProductionKnobLabel          matlab.ui.control.Label
        OC_ProductionKnob               matlab.ui.control.Knob
        InitialRatesPg10MyrLabel_2      matlab.ui.control.Label
        OC_SubductionKnobLabel          matlab.ui.control.Label
        OC_SubductionKnob               matlab.ui.control.Knob
        Arc_VolcanismKnobLabel          matlab.ui.control.Label
        Arc_VolcanismKnob               matlab.ui.control.Knob
        InitialParameters22Tab          matlab.ui.container.Tab
        CIR_WeathKnobLabel              matlab.ui.control.Label
        CIR_WeathKnob                   matlab.ui.control.Knob
        LithificationKnobLabel          matlab.ui.control.Label
        LithificationKnob               matlab.ui.control.Knob
        SR_WeathKnobLabel               matlab.ui.control.Label
        SR_WeathKnob                    matlab.ui.control.Knob
        SR_MetaKnobLabel                matlab.ui.control.Label
        SR_MetaKnob                     matlab.ui.control.Knob
        CIR_MetaKnobLabel               matlab.ui.control.Label
        CIR_MetaKnob                    matlab.ui.control.Knob
        MR_WeathKnobLabel               matlab.ui.control.Label
        MR_WeathKnob                    matlab.ui.control.Knob
        MeltingKnobLabel                matlab.ui.control.Label
        MeltingKnob                     matlab.ui.control.Knob
        MR_SubductionKnobLabel          matlab.ui.control.Label
        MR_SubductionKnob               matlab.ui.control.Knob
        EffectofHumanActivitiesTab      matlab.ui.container.Tab
        WeatheringEditFieldLabel        matlab.ui.control.Label
        WeatheringEditField             matlab.ui.control.NumericEditField
        QuarryingEditFieldLabel         matlab.ui.control.Label
        QuarryingEditField              matlab.ui.control.NumericEditField
        QuarryingKnobLabel              matlab.ui.control.Label
        QuarryingKnob                   matlab.ui.control.Knob
        WeatheringRateFactorKnobLabel   matlab.ui.control.Label
        WeatheringRateFactorKnob        matlab.ui.control.Knob
        TextArea                        matlab.ui.control.TextArea
        ResetButton                     matlab.ui.control.Button
        MantleTab                       matlab.ui.container.Tab
        mantlePlot                      matlab.ui.control.UIAxes
        OceanCrustTab                   matlab.ui.container.Tab
        oceanPlot                       matlab.ui.control.UIAxes
        IgneousRocksTab                 matlab.ui.container.Tab
        igneousPlot                     matlab.ui.control.UIAxes
        MetamorphicRocksTab             matlab.ui.container.Tab
        metaPlot                        matlab.ui.control.UIAxes
        SedimentaryRocksTab             matlab.ui.container.Tab
        sedimentaryPlot                 matlab.ui.control.UIAxes
        SedimentsTab                    matlab.ui.container.Tab
        sedimentsPlot                   matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: RunandPlotButton
        function RunandPlotButtonPushed(app, event)
            time = uint64(app.SimulationTime10MyrSlider.Value);
            t=1:time;

            Mantle = zeros( 1 , time , 'double' );
            Ocean_Crust = zeros( 1 , time , 'double' );
            Cont_Ig_Rx = zeros( 1 , time , 'double' );
            Meta_Rx = zeros( 1 , time , 'double' );
            Sed_Rx = zeros( 1 , time , 'double' );
            Sed = zeros( 1 , time , 'double' );

            WR_Factor = app.WeatheringEditField.Value;
            Quarrying = app.QuarryingEditField.Value;
            
            
            Rate_OC_Production = app.Rate_OC_ProductionEditField.Value;
            Rate_OC_Subduction = app.Rate_OC_SubductionEditField.Value;
            Rate_Arc_Volcanism = app.Rate_Arc_VolcanismEditField.Value;
            Rate_CIR_Weath = WR_Factor * app.Rate_CIR_WeathEditField.Value;
            Rate_CIR_Meta = app.Rate_CIR_MetaEditField.Value;
            Rate_Lithification = app.Rate_LithificationEditField.Value;
            Rate_SR_Weath = WR_Factor * app.Rate_SR_WeathEditField.Value;
            Rate_SR_Meta = app.Rate_SR_MetaEditField.Value;
            Rate_MR_Weath = WR_Factor * app.Rate_MR_WeathEditField.Value;
            Rate_Melting = app.Rate_MeltingEditField.Value;
            Rate_MR_Subduction = app.Rate_MR_SubductionEditField.Value;

            Init_Mantle = app.Init_MantleEditField.Value;
            Init_Ocean_Crust = app.Init_Ocean_CrustEditField.Value;
            Init_Cont_Ig_Rx = app.Init_Cont_Ig_RxEditField.Value;
            Init_Meta_Rx = app.Init_Meta_RxEditField.Value;
            Init_Sed_Rx = app.Init_Sed_RxEditField.Value;
            Init_Sed = app.Init_SedEditField.Value;
        
            Mantle(1) = Init_Mantle;
            Ocean_Crust(1) = Init_Ocean_Crust;
            Cont_Ig_Rx(1) = Init_Cont_Ig_Rx;
            Meta_Rx(1) = Init_Meta_Rx;
            Sed_Rx(1) = Init_Sed_Rx;
            Sed(1) = Init_Sed;
   
            for i = 2:time
                OC_Production = Rate_OC_Production;
                OC_Subduction = Rate_OC_Subduction;
                Arc_Volcanism = Rate_Arc_Volcanism;
                CIR_Weath = Cont_Ig_Rx(i - 1)  * ( Rate_CIR_Weath / Init_Cont_Ig_Rx );
                CIR_Meta = Cont_Ig_Rx(i - 1) * ( Rate_CIR_Meta / Init_Cont_Ig_Rx );
                Lithification = Sed(i - 1) * ( Rate_Lithification / Init_Sed );
                SR_Weath = Sed_Rx(i - 1) * ( Rate_SR_Weath / Init_Sed_Rx ); 
                SR_Meta = Sed_Rx(i - 1) * ( Rate_SR_Meta / Init_Sed_Rx );
                MR_Weath = Meta_Rx(i - 1) * ( Rate_MR_Weath / Init_Meta_Rx );
                Melting = Meta_Rx(i - 1) * ( Rate_Melting / Init_Meta_Rx );
                MR_Subduction = Meta_Rx(i - 1) * ( Rate_MR_Subduction / Init_Meta_Rx ); 

                Mantle(i) = Mantle(i - 1) + OC_Subduction + MR_Subduction - OC_Production - Arc_Volcanism;
                Ocean_Crust(i) =  Ocean_Crust(i - 1) + OC_Production - OC_Subduction;
                Cont_Ig_Rx(i) = Cont_Ig_Rx(i - 1) + Arc_Volcanism + Melting - CIR_Meta - CIR_Weath;
                Meta_Rx(i) = Meta_Rx(i - 1) + CIR_Meta + SR_Meta - Melting - MR_Weath - MR_Subduction;
                Sed_Rx(i) = Sed_Rx(i - 1) + Lithification - SR_Weath - SR_Meta - Quarrying;
                Sed(i) = Sed(i - 1) + CIR_Weath + MR_Weath + SR_Weath - Lithification;
            end

            %title('Rock Cycle');
            %ylabel('Mass ( * 1E15 gram )');
            %xlabel('Time ( * 10 mil year )');

            
            plot(app.mantlePlot, t , Mantle , '-ko', 'MarkerSize', 4, 'MarkerFaceColor', 'r','LineWidth',1);
            plot(app.oceanPlot, t , Ocean_Crust, '-ko', 'MarkerSize', 4, 'MarkerFaceColor', 'r','LineWidth',1);
            plot(app.igneousPlot, t , Cont_Ig_Rx, '-ko', 'MarkerSize', 4, 'MarkerFaceColor', 'r','LineWidth',1);
            plot(app.metaPlot, t , Meta_Rx, '-ko', 'MarkerSize', 4, 'MarkerFaceColor', 'r','LineWidth',1);
            plot(app.sedimentaryPlot, t , Sed_Rx, '-ko', 'MarkerSize', 4, 'MarkerFaceColor', 'r','LineWidth',1);
            plot(app.sedimentsPlot, t , Sed, '-ko', 'MarkerSize', 4, 'MarkerFaceColor', 'r','LineWidth',1);
            
        end

        % Button pushed function: ResetSteadyStateButton
        function ResetSteadyStateButtonPushed(app, event)
            app.Rate_OC_ProductionEditField.Value = 7.0E8;
            app.Rate_OC_SubductionEditField.Value = 7.0E8;
            app.Rate_Arc_VolcanismEditField.Value = 6.0E7;
            app.Rate_CIR_WeathEditField.Value = 5.0E7;
            app.Rate_CIR_MetaEditField.Value = 2.0E7;
            app.Rate_LithificationEditField.Value = 9.0E7;
            app.Rate_SR_WeathEditField.Value = 2.0E7;
            app.Rate_SR_MetaEditField.Value = 7.0E7;
            app.Rate_MR_WeathEditField.Value = 2.0E7;
            app.Rate_MeltingEditField.Value = 1.0E7;
            app.Rate_MR_SubductionEditField.Value = 6.0E7;
            
            app.Init_MantleEditField.Value = 9.8E11;
            app.Init_Ocean_CrustEditField.Value = 5.39E9;
            app.Init_Cont_Ig_RxEditField.Value = 1.33E10;
            app.Init_Meta_RxEditField.Value = 5.62E9;
            app.Init_Sed_RxEditField.Value = 1.50E9;
            app.Init_SedEditField.Value = 1.32E8;   
            
            app.MantleKnob.Value = 9.8E11;
            app.OceanCrustKnob.Value = 5.39E9;
            app.IgneousRocksKnob.Value = 1.33E10;
            app.MetamorphicRocksKnob.Value = 5.62E9;
            app.SedimentaryRocksKnob.Value = 1.50E9;
            app.SedimentsKnob.Value = 1.32E8;
            
            app.OC_ProductionKnob.Value = 7.0E8;
            app.OC_SubductionKnob.Value = 7.0E8;
            app.Arc_VolcanismKnob.Value = 6E7;
            app.CIR_WeathKnob.Value = 5E7;
            app.CIR_MetaKnob.Value = 2E7; 
            app.LithificationKnob.Value = 9E7;
            app.SR_WeathKnob.Value = 2E7;
            app.SR_MetaKnob.Value = 7E7;
            app.MR_WeathKnob.Value = 2E7;
            app.MeltingKnob.Value = 1E7;
            app.MR_SubductionKnob.Value = 6E7;
            
        end

        % Value changed function: MantleKnob
        function initMantleChanged(app, event)
            value = app.MantleKnob.Value;
            app.Init_MantleEditField.Value = value;
        end

        % Value changed function: IgneousRocksKnob
        function initIgnChanged(app, event)
            value = app.IgneousRocksKnob.Value;
            app.Init_Cont_Ig_RxEditField.Value = value;
        end

        % Value changed function: SedimentaryRocksKnob
        function initSedChanged(app, event)
            value = app.SedimentaryRocksKnob.Value;
            app.Init_Sed_RxEditField.Value = value;
        end

        % Value changed function: MetamorphicRocksKnob
        function MetamorphicRocksKnobValueChanged(app, event)
            value = app.MetamorphicRocksKnob.Value;
            app.Init_Meta_RxEditField.Value = value;
        end

        % Value changed function: OceanCrustKnob
        function OceanCrustKnobValueChanged(app, event)
            value = app.OceanCrustKnob.Value;
            app.Init_Ocean_CrustEditField.Value = value;
        end

        % Value changed function: SedimentsKnob
        function SedimentsKnobValueChanged(app, event)
            value = app.SedimentsKnob.Value;
            app.Init_SedEditField.Value = value;
        end

        % Callback function
        function ArcVolcanismKnobValueChanged(app, event)
            value = app.ArcVolcanismKnob.Value;
            app.Rate_Arc_VolcanismEditField.Value = value;
        end

        % Value changed function: OC_ProductionKnob
        function OC_ProductionKnobValueChanged(app, event)
            value = app.OC_ProductionKnob.Value;
            app.Rate_OC_ProductionEditField.Value = value;
        end

        % Value changed function: OC_SubductionKnob
        function OC_SubductionKnobValueChanged(app, event)
            value = app.OC_SubductionKnob.Value;
            app.Rate_OC_SubductionEditField.Value = value;
        end

        % Value changed function: Arc_VolcanismKnob
        function Arc_VolcanismKnobValueChanged(app, event)
            value = app.Arc_VolcanismKnob.Value;
            app.Rate_Arc_VolcanismEditField.Value = value;
        end

        % Value changed function: CIR_WeathKnob
        function CIR_WeathKnobValueChanged(app, event)
            value = app.CIR_WeathKnob.Value;
            app.Rate_CIR_WeathEditField.Value = value;
        end

        % Value changed function: CIR_MetaKnob
        function CIR_MetaKnobValueChanged(app, event)
            value = app.CIR_MetaKnob.Value;
            app.Rate_CIR_MetaEditField.Value = value;
        end

        % Value changed function: LithificationKnob
        function LithificationKnobValueChanged(app, event)
            value = app.LithificationKnob.Value;
            app.Rate_LithificationEditField.Value = value;
        end

        % Value changed function: SR_WeathKnob
        function SR_WeathKnobValueChanged(app, event)
            value = app.SR_WeathKnob.Value;
            app.Rate_SR_WeathEditField.Value = value;
        end

        % Value changed function: SR_MetaKnob
        function SR_MetaKnobValueChanged(app, event)
            value = app.SR_MetaKnob.Value;
            app.Rate_SR_MetaEditField.Value = value;
        end

        % Value changed function: MR_WeathKnob
        function MR_WeathKnobValueChanged(app, event)
            value = app.MR_WeathKnob.Value;
            app.Rate_MR_WeathEditField.Value = value;
        end

        % Value changed function: MeltingKnob
        function MeltingKnobValueChanged(app, event)
            value = app.MeltingKnob.Value;
            app.Rate_MeltingEditField.Value = value;
        end

        % Value changed function: MR_SubductionKnob
        function MR_SubductionKnobValueChanged(app, event)
            value = app.MR_SubductionKnob.Value;
            app.Rate_MR_SubductionEditField.Value = value;
        end

        % Value changed function: WeatheringRateFactorKnob
        function WeatheringRateFactorKnobValueChanged(app, event)
            value = app.WeatheringRateFactorKnob.Value;
            app.WeatheringEditField.Value = value;
        end

        % Value changed function: QuarryingKnob
        function QuarryingKnobValueChanged(app, event)
            value = app.QuarryingKnob.Value;
            app.QuarryingEditField.Value = value;
        end

        % Callback function
        function SedimentaryCoalMiningKnobValueChanged(app, event)
            value = app.SedimentaryCoalMiningKnob.Value;
            app.SedCoalMiningEditField.Value = value;
        end

        % Callback function
        function MetamorphicCoalMiningKnobValueChanged(app, event)
            value = app.MetamorphicCoalMiningKnob.Value;
            app.MetCoalMiningEditField.Value = value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.WeatheringEditField.Value = 1;
            app.QuarryingEditField.Value = 0;
            
            
            app.WeatheringRateFactorKnob.Value = 1;
            app.QuarryingKnob.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ENSGeologicalCycleUIFigure = uifigure;
            app.ENSGeologicalCycleUIFigure.Name = 'ENS Geological Cycle';
            app.TabGroup = uitabgroup(app.ENSGeologicalCycleUIFigure);
            app.Introduction12Tab = uitab(app.TabGroup);
            app.Introduction12Tab.Title = 'Introduction(1/2)';
            app.Label = uilabel(app.Introduction12Tab);
            app.Label.Text = '';
            app.TextArea_2 = uitextarea(app.Introduction12Tab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'This application models the geological cycle using equations that follow the given flow chart. This flow chart describes the transfer of materials between different stages of the rock cycle in accordance with appropriate processes. In this model the instantaneous process rates directly proportional to the instantaneous reservoir mass.'; ''; 'To simulate the impact of human activities on the rock cycle, we have considered 2 specific instances:'; '1) Weathering Rate Factor : Increase in the amount of acidic gases like CO2 increases the acidity of water which in turn increases the weathering rate.'; '2) Quarrying : Specifically quarrying of Limestone(a type of Sedimentary Rock) disturbs the balance between different stages of the rock cycle.'; ''};
            app.Button = uibutton(app.Introduction12Tab, 'push');
            app.Button.Icon = 'flow.PNG';
            app.Button.Text = '';
            app.RockCycleModelLabel = uilabel(app.Introduction12Tab);
            app.RockCycleModelLabel.Text = 'Rock Cycle Model';
            app.Introduction22Tab = uitab(app.TabGroup);
            app.Introduction22Tab.Title = 'Introduction(2/2)';
            app.Label_3 = uilabel(app.Introduction22Tab);
            app.Label_3.Text = '';
            app.TextArea_3 = uitextarea(app.Introduction22Tab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'For reservoirs: '; ''; 'Init_Mantle : Initial mass of mantle in Petagrams'; 'Init_Ocean_Crust : Initial mass of ocean crust in Petagrams'; 'Init_Cont_Ig_Rx : Initial mass of Continental Igneous rocks in Petagrams '; 'Init_Meta_Rx : Initial mass of metamorphic rocks in Petagrams'; 'Init_Sed_Rx : Initial mass of Sedimentary Rocks in Petagrams'; 'Init_Sed : Initial mass of Sediments in Petagrams'; ''; 'For initial process rates:'; ''; 'Rate_OC_Production : Rate of ocean crust production in Petagrams per 10 million years'; 'Rate_OC_Subduction : Rate of ocean crust subduction in Petagrams per 10 million years'; 'Rate_Arc_Volcanism : Rate of arc volcanism in Petagrams per 10 million years'; 'Rate_CIR_Weath : Rate of continental igneous rock weathering production in Petagrams per 10 million years'; 'Rate_CIR_Meta : Rate of continental igneous rock metamorphism in Petagrams per 10 million years'; 'Rate_Lithification : Rate of lithification in Petagrams per 10 million years'; 'Rate_SR_Weath : Rate of sedimentary rock weathering in Petagrams per 10 million years'; 'Rate_SR_Meta : Rate of sedimentary rock metamorphism in Petagrams per 10 million years'; 'Rate_MR_Weath : Rate of metamorphic rock weathering in Petagrams per 10 million years'; 'Rate_Melting : Rate of melting in Petagrams per 10 million years'; 'Rate_MR_Subduction : Rate of metamorphic rock subduction in Petagrams per 10 million years'; ''; ''};
            app.AcronymsforparametersLabel = uilabel(app.Introduction22Tab);
            app.AcronymsforparametersLabel.Text = 'Acronyms for parameters';
            app.params_Data = uitab(app.TabGroup);
            app.params_Data.Title = 'Parameters And Data';
            app.Init_MantleEditFieldLabel = uilabel(app.params_Data);
            app.Init_MantleEditFieldLabel.Text = 'Init_Mantle';
            app.Init_MantleEditField = uieditfield(app.params_Data, 'numeric');
            app.Init_MantleEditField.ValueDisplayFormat = '%e';
            app.Init_MantleEditField.Editable = 'off';
            app.Init_MantleEditField.Value = 980000000000;
            app.ResetSteadyStateButton = uibutton(app.params_Data, 'push');
            app.ResetSteadyStateButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSteadyStateButtonPushed, true);
            app.ResetSteadyStateButton.Text = 'Reset Steady State';
            app.RunandPlotButton = uibutton(app.params_Data, 'push');
            app.RunandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RunandPlotButtonPushed, true);
            app.RunandPlotButton.Text = 'Run and Plot';
            app.InitialMassPgLabel_2 = uilabel(app.params_Data);
            app.InitialMassPgLabel_2.Text = 'Initial Mass( Pg )';
            app.InitialRatesPg10MyrLabel = uilabel(app.params_Data);
            app.InitialRatesPg10MyrLabel.Text = 'Initial Rates( Pg / 10 Myr )';
            app.Init_Ocean_CrustEditFieldLabel = uilabel(app.params_Data);
            app.Init_Ocean_CrustEditFieldLabel.Text = 'Init_Ocean_Crust';
            app.Init_Ocean_CrustEditField = uieditfield(app.params_Data, 'numeric');
            app.Init_Ocean_CrustEditField.ValueDisplayFormat = '%e';
            app.Init_Ocean_CrustEditField.Editable = 'off';
            app.Init_Ocean_CrustEditField.Value = 5390000000;
            app.Init_Cont_Ig_RxEditFieldLabel = uilabel(app.params_Data);
            app.Init_Cont_Ig_RxEditFieldLabel.Text = 'Init_Cont_Ig_Rx';
            app.Init_Cont_Ig_RxEditField = uieditfield(app.params_Data, 'numeric');
            app.Init_Cont_Ig_RxEditField.ValueDisplayFormat = '%e';
            app.Init_Cont_Ig_RxEditField.Editable = 'off';
            app.Init_Cont_Ig_RxEditField.Value = 13300000000;
            app.Init_SedEditFieldLabel = uilabel(app.params_Data);
            app.Init_SedEditFieldLabel.Text = 'Init_Sed';
            app.Init_SedEditField = uieditfield(app.params_Data, 'numeric');
            app.Init_SedEditField.ValueDisplayFormat = '%e';
            app.Init_SedEditField.Editable = 'off';
            app.Init_SedEditField.Value = 132000000;
            app.Rate_OC_SubductionEditFieldLabel = uilabel(app.params_Data);
            app.Rate_OC_SubductionEditFieldLabel.Text = 'Rate_OC_Subduction';
            app.Rate_OC_SubductionEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_OC_SubductionEditField.ValueDisplayFormat = '%e';
            app.Rate_OC_SubductionEditField.Editable = 'off';
            app.Rate_OC_SubductionEditField.Value = 700000000;
            app.Rate_Arc_VolcanismEditFieldLabel = uilabel(app.params_Data);
            app.Rate_Arc_VolcanismEditFieldLabel.Text = 'Rate_Arc_Volcanism';
            app.Rate_Arc_VolcanismEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_Arc_VolcanismEditField.ValueDisplayFormat = '%e';
            app.Rate_Arc_VolcanismEditField.Editable = 'off';
            app.Rate_Arc_VolcanismEditField.Value = 60000000;
            app.Rate_CIR_WeathEditFieldLabel = uilabel(app.params_Data);
            app.Rate_CIR_WeathEditFieldLabel.Text = 'Rate_CIR_Weath';
            app.Rate_CIR_WeathEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_CIR_WeathEditField.ValueDisplayFormat = '%e';
            app.Rate_CIR_WeathEditField.Editable = 'off';
            app.Rate_CIR_WeathEditField.Value = 50000000;
            app.Rate_CIR_MetaEditFieldLabel = uilabel(app.params_Data);
            app.Rate_CIR_MetaEditFieldLabel.Text = 'Rate_CIR_Meta';
            app.Rate_CIR_MetaEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_CIR_MetaEditField.ValueDisplayFormat = '%e';
            app.Rate_CIR_MetaEditField.Editable = 'off';
            app.Rate_CIR_MetaEditField.Value = 20000000;
            app.Rate_LithificationEditFieldLabel = uilabel(app.params_Data);
            app.Rate_LithificationEditFieldLabel.Text = 'Rate_Lithification';
            app.Rate_LithificationEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_LithificationEditField.ValueDisplayFormat = '%e';
            app.Rate_LithificationEditField.Editable = 'off';
            app.Rate_LithificationEditField.Value = 90000000;
            app.Rate_OC_ProductionEditFieldLabel = uilabel(app.params_Data);
            app.Rate_OC_ProductionEditFieldLabel.Text = 'Rate_OC_Production';
            app.Rate_OC_ProductionEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_OC_ProductionEditField.ValueDisplayFormat = '%e';
            app.Rate_OC_ProductionEditField.Editable = 'off';
            app.Rate_OC_ProductionEditField.Value = 700000000;
            app.Rate_SR_MetaEditFieldLabel = uilabel(app.params_Data);
            app.Rate_SR_MetaEditFieldLabel.Text = 'Rate_SR_Meta';
            app.Rate_SR_MetaEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_SR_MetaEditField.ValueDisplayFormat = '%e';
            app.Rate_SR_MetaEditField.Editable = 'off';
            app.Rate_SR_MetaEditField.Value = 70000000;
            app.Rate_MR_WeathEditFieldLabel = uilabel(app.params_Data);
            app.Rate_MR_WeathEditFieldLabel.Text = 'Rate_MR_Weath';
            app.Rate_MR_WeathEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_MR_WeathEditField.ValueDisplayFormat = '%e';
            app.Rate_MR_WeathEditField.Editable = 'off';
            app.Rate_MR_WeathEditField.Value = 20000000;
            app.Rate_MeltingEditFieldLabel = uilabel(app.params_Data);
            app.Rate_MeltingEditFieldLabel.Text = 'Rate_Melting';
            app.Rate_MeltingEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_MeltingEditField.ValueDisplayFormat = '%e';
            app.Rate_MeltingEditField.Editable = 'off';
            app.Rate_MeltingEditField.Value = 10000000;
            app.Rate_MR_SubductionEditFieldLabel = uilabel(app.params_Data);
            app.Rate_MR_SubductionEditFieldLabel.Text = 'Rate_MR_Subduction';
            app.Rate_MR_SubductionEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_MR_SubductionEditField.ValueDisplayFormat = '%e';
            app.Rate_MR_SubductionEditField.Editable = 'off';
            app.Rate_MR_SubductionEditField.Value = 60000000;
            app.Rate_SR_WeathEditFieldLabel = uilabel(app.params_Data);
            app.Rate_SR_WeathEditFieldLabel.Text = 'Rate_SR_Weath';
            app.Rate_SR_WeathEditField = uieditfield(app.params_Data, 'numeric');
            app.Rate_SR_WeathEditField.ValueDisplayFormat = '%e';
            app.Rate_SR_WeathEditField.Editable = 'off';
            app.Rate_SR_WeathEditField.Value = 20000000;
            app.SimulationTime10MyrSliderLabel = uilabel(app.params_Data);
            app.SimulationTime10MyrSliderLabel.Text = 'Simulation Time( 10 Myr )';
            app.SimulationTime10MyrSlider = uislider(app.params_Data);
            app.SimulationTime10MyrSlider.Limits = [1 100];
            app.SimulationTime10MyrSlider.MajorTicks = [1 10 19 28 37 46 55 64 73 82 91 100];
            app.SimulationTime10MyrSlider.Value = 10;
            app.Init_Meta_RxEditFieldLabel = uilabel(app.params_Data);
            app.Init_Meta_RxEditFieldLabel.Text = 'Init_Meta_Rx ';
            app.Init_Meta_RxEditField = uieditfield(app.params_Data, 'numeric');
            app.Init_Meta_RxEditField.ValueDisplayFormat = '%e';
            app.Init_Meta_RxEditField.Editable = 'off';
            app.Init_Meta_RxEditField.Value = 5620000000;
            app.Init_Sed_RxEditFieldLabel = uilabel(app.params_Data);
            app.Init_Sed_RxEditFieldLabel.Text = 'Init_Sed_Rx';
            app.Init_Sed_RxEditField = uieditfield(app.params_Data, 'numeric');
            app.Init_Sed_RxEditField.ValueDisplayFormat = '%e';
            app.Init_Sed_RxEditField.Editable = 'off';
            app.Init_Sed_RxEditField.Value = 1500000000;
            app.InitialParameters12Tab = uitab(app.TabGroup);
            app.InitialParameters12Tab.Title = 'Initial Parameters(1/2)';
            app.MantleKnobLabel = uilabel(app.InitialParameters12Tab);
            app.MantleKnobLabel.Text = 'Mantle';
            app.MantleKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.MantleKnob.Limits = [98000000000 9800000000000];
            app.MantleKnob.ValueChangedFcn = createCallbackFcn(app, @initMantleChanged, true);
            app.MantleKnob.Value = 980000000000;
            app.IgneousRocksKnobLabel = uilabel(app.InitialParameters12Tab);
            app.IgneousRocksKnobLabel.Text = 'Igneous Rocks';
            app.IgneousRocksKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.IgneousRocksKnob.Limits = [1330000000 133000000000];
            app.IgneousRocksKnob.ValueChangedFcn = createCallbackFcn(app, @initIgnChanged, true);
            app.IgneousRocksKnob.Value = 13300000000;
            app.MetamorphicRocksKnobLabel = uilabel(app.InitialParameters12Tab);
            app.MetamorphicRocksKnobLabel.Text = 'Metamorphic Rocks';
            app.MetamorphicRocksKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.MetamorphicRocksKnob.Limits = [562000000 56200000000];
            app.MetamorphicRocksKnob.ValueChangedFcn = createCallbackFcn(app, @MetamorphicRocksKnobValueChanged, true);
            app.MetamorphicRocksKnob.Value = 5620000000;
            app.SedimentaryRocksKnobLabel = uilabel(app.InitialParameters12Tab);
            app.SedimentaryRocksKnobLabel.Text = 'Sedimentary Rocks';
            app.SedimentaryRocksKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.SedimentaryRocksKnob.Limits = [150000000 15000000000];
            app.SedimentaryRocksKnob.ValueChangedFcn = createCallbackFcn(app, @initSedChanged, true);
            app.SedimentaryRocksKnob.Value = 1500000000;
            app.OceanCrustKnobLabel = uilabel(app.InitialParameters12Tab);
            app.OceanCrustKnobLabel.Text = 'Ocean Crust';
            app.OceanCrustKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.OceanCrustKnob.Limits = [539000000 53900000000];
            app.OceanCrustKnob.ValueChangedFcn = createCallbackFcn(app, @OceanCrustKnobValueChanged, true);
            app.OceanCrustKnob.Value = 5390000000;
            app.SedimentsKnobLabel = uilabel(app.InitialParameters12Tab);
            app.SedimentsKnobLabel.Text = 'Sediments';
            app.SedimentsKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.SedimentsKnob.Limits = [13200000 1320000000];
            app.SedimentsKnob.ValueChangedFcn = createCallbackFcn(app, @SedimentsKnobValueChanged, true);
            app.SedimentsKnob.Value = 132000000;
            app.InitialMassPgLabel = uilabel(app.InitialParameters12Tab);
            app.InitialMassPgLabel.Text = 'Initial Mass( Pg )';
            app.OC_ProductionKnobLabel = uilabel(app.InitialParameters12Tab);
            app.OC_ProductionKnobLabel.Text = 'OC_Production';
            app.OC_ProductionKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.OC_ProductionKnob.Limits = [70000000 7000000000];
            app.OC_ProductionKnob.ValueChangedFcn = createCallbackFcn(app, @OC_ProductionKnobValueChanged, true);
            app.OC_ProductionKnob.Value = 700000000;
            app.InitialRatesPg10MyrLabel_2 = uilabel(app.InitialParameters12Tab);
            app.InitialRatesPg10MyrLabel_2.Text = 'Initial Rates( Pg / 10 Myr )';
            app.OC_SubductionKnobLabel = uilabel(app.InitialParameters12Tab);
            app.OC_SubductionKnobLabel.Text = 'OC_Subduction';
            app.OC_SubductionKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.OC_SubductionKnob.Limits = [70000000 7000000000];
            app.OC_SubductionKnob.ValueChangedFcn = createCallbackFcn(app, @OC_SubductionKnobValueChanged, true);
            app.OC_SubductionKnob.Value = 700000000;
            app.Arc_VolcanismKnobLabel = uilabel(app.InitialParameters12Tab);
            app.Arc_VolcanismKnobLabel.Text = 'Arc_Volcanism';
            app.Arc_VolcanismKnob = uiknob(app.InitialParameters12Tab, 'continuous');
            app.Arc_VolcanismKnob.Limits = [6000000 600000000];
            app.Arc_VolcanismKnob.ValueChangedFcn = createCallbackFcn(app, @Arc_VolcanismKnobValueChanged, true);
            app.Arc_VolcanismKnob.Value = 60000000;
            app.InitialParameters22Tab = uitab(app.TabGroup);
            app.InitialParameters22Tab.Title = 'Initial Parameters(2/2)';
            app.CIR_WeathKnobLabel = uilabel(app.InitialParameters22Tab);
            app.CIR_WeathKnobLabel.Text = 'CIR_Weath';
            app.CIR_WeathKnob = uiknob(app.InitialParameters22Tab, 'continuous');
            app.CIR_WeathKnob.Limits = [5000000 500000000];
            app.CIR_WeathKnob.ValueChangedFcn = createCallbackFcn(app, @CIR_WeathKnobValueChanged, true);
            app.CIR_WeathKnob.Value = 50000000;
            app.LithificationKnobLabel = uilabel(app.InitialParameters22Tab);
            app.LithificationKnobLabel.Text = 'Lithification';
            app.LithificationKnob = uiknob(app.InitialParameters22Tab, 'continuous');
            app.LithificationKnob.Limits = [9000000 900000000];
            app.LithificationKnob.ValueChangedFcn = createCallbackFcn(app, @LithificationKnobValueChanged, true);
            app.LithificationKnob.Value = 90000000;
            app.SR_WeathKnobLabel = uilabel(app.InitialParameters22Tab);
            app.SR_WeathKnobLabel.Text = 'SR_Weath';
            app.SR_WeathKnob = uiknob(app.InitialParameters22Tab, 'continuous');
            app.SR_WeathKnob.Limits = [2000000 200000000];
            app.SR_WeathKnob.ValueChangedFcn = createCallbackFcn(app, @SR_WeathKnobValueChanged, true);
            app.SR_WeathKnob.Value = 20000000;
            app.SR_MetaKnobLabel = uilabel(app.InitialParameters22Tab);
            app.SR_MetaKnobLabel.Text = 'SR_Meta';
            app.SR_MetaKnob = uiknob(app.InitialParameters22Tab, 'continuous');
            app.SR_MetaKnob.Limits = [7000000 700000000];
            app.SR_MetaKnob.ValueChangedFcn = createCallbackFcn(app, @SR_MetaKnobValueChanged, true);
            app.SR_MetaKnob.Value = 70000000;
            app.CIR_MetaKnobLabel = uilabel(app.InitialParameters22Tab);
            app.CIR_MetaKnobLabel.Text = 'CIR_Meta';
            app.CIR_MetaKnob = uiknob(app.InitialParameters22Tab, 'continuous');
            app.CIR_MetaKnob.Limits = [2000000 200000000];
            app.CIR_MetaKnob.ValueChangedFcn = createCallbackFcn(app, @CIR_MetaKnobValueChanged, true);
            app.CIR_MetaKnob.Value = 20000000;
            app.MR_WeathKnobLabel = uilabel(app.InitialParameters22Tab);
            app.MR_WeathKnobLabel.Text = 'MR_Weath';
            app.MR_WeathKnob = uiknob(app.InitialParameters22Tab, 'continuous');
            app.MR_WeathKnob.Limits = [2000000 200000000];
            app.MR_WeathKnob.ValueChangedFcn = createCallbackFcn(app, @MR_WeathKnobValueChanged, true);
            app.MR_WeathKnob.Value = 20000000;
            app.MeltingKnobLabel = uilabel(app.InitialParameters22Tab);
            app.MeltingKnobLabel.Text = 'Melting';
            app.MeltingKnob = uiknob(app.InitialParameters22Tab, 'continuous');
            app.MeltingKnob.Limits = [1000000 100000000];
            app.MeltingKnob.ValueChangedFcn = createCallbackFcn(app, @MeltingKnobValueChanged, true);
            app.MeltingKnob.Value = 10000000;
            app.MR_SubductionKnobLabel = uilabel(app.InitialParameters22Tab);
            app.MR_SubductionKnobLabel.Text = 'MR_Subduction';
            app.MR_SubductionKnob = uiknob(app.InitialParameters22Tab, 'continuous');
            app.MR_SubductionKnob.Limits = [6000000 600000000];
            app.MR_SubductionKnob.ValueChangedFcn = createCallbackFcn(app, @MR_SubductionKnobValueChanged, true);
            app.MR_SubductionKnob.Value = 60000000;
            app.EffectofHumanActivitiesTab = uitab(app.TabGroup);
            app.EffectofHumanActivitiesTab.Title = 'Effect of Human Activities';
            app.WeatheringEditFieldLabel = uilabel(app.EffectofHumanActivitiesTab);
            app.WeatheringEditFieldLabel.Text = 'Weathering';
            app.WeatheringEditField = uieditfield(app.EffectofHumanActivitiesTab, 'numeric');
            app.WeatheringEditField.Editable = 'off';
            app.WeatheringEditField.Value = 1;
            app.QuarryingEditFieldLabel = uilabel(app.EffectofHumanActivitiesTab);
            app.QuarryingEditFieldLabel.Text = 'Quarrying';
            app.QuarryingEditField = uieditfield(app.EffectofHumanActivitiesTab, 'numeric');
            app.QuarryingEditField.Editable = 'off';
            app.QuarryingKnobLabel = uilabel(app.EffectofHumanActivitiesTab);
            app.QuarryingKnobLabel.Text = 'Quarrying';
            app.QuarryingKnob = uiknob(app.EffectofHumanActivitiesTab, 'continuous');
            app.QuarryingKnob.Limits = [0 180000000];
            app.QuarryingKnob.ValueChangedFcn = createCallbackFcn(app, @QuarryingKnobValueChanged, true);
            app.WeatheringRateFactorKnobLabel = uilabel(app.EffectofHumanActivitiesTab);
            app.WeatheringRateFactorKnobLabel.Text = 'Weathering Rate Factor';
            app.WeatheringRateFactorKnob = uiknob(app.EffectofHumanActivitiesTab, 'continuous');
            app.WeatheringRateFactorKnob.Limits = [1 2];
            app.WeatheringRateFactorKnob.ValueChangedFcn = createCallbackFcn(app, @WeatheringRateFactorKnobValueChanged, true);
            app.WeatheringRateFactorKnob.Value = 1;
            app.TextArea = uitextarea(app.EffectofHumanActivitiesTab);
            app.TextArea.Value = {'Initial Rates( Pg / 10 Myr )'};
            app.ResetButton = uibutton(app.EffectofHumanActivitiesTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset ';
            app.MantleTab = uitab(app.TabGroup);
            app.MantleTab.Title = 'Mantle';
            app.mantlePlot = uiaxes(app.MantleTab);
            title(app.mantlePlot, 'Mantle')
            xlabel(app.mantlePlot, 'Time( 10 Myr )')
            ylabel(app.mantlePlot, 'Mass( Pg )')
            app.OceanCrustTab = uitab(app.TabGroup);
            app.OceanCrustTab.Title = 'Ocean Crust';
            app.oceanPlot = uiaxes(app.OceanCrustTab);
            title(app.oceanPlot, 'Ocean Crust')
            xlabel(app.oceanPlot, 'Time( 10 Myr )')
            ylabel(app.oceanPlot, 'Mass( Pg )')
            app.IgneousRocksTab = uitab(app.TabGroup);
            app.IgneousRocksTab.Title = 'Igneous Rocks';
            app.igneousPlot = uiaxes(app.IgneousRocksTab);
            title(app.igneousPlot, 'Igneous Rocks')
            xlabel(app.igneousPlot, 'Time( 10 Myr )')
            ylabel(app.igneousPlot, 'Mass( Pg )')
            app.MetamorphicRocksTab = uitab(app.TabGroup);
            app.MetamorphicRocksTab.Title = 'Metamorphic Rocks';
            app.metaPlot = uiaxes(app.MetamorphicRocksTab);
            title(app.metaPlot, 'Metamorphic Rocks')
            xlabel(app.metaPlot, 'Time( 10 Myr )')
            ylabel(app.metaPlot, 'Mass( Pg )')
            app.SedimentaryRocksTab = uitab(app.TabGroup);
            app.SedimentaryRocksTab.Title = 'Sedimentary Rocks';
            app.sedimentaryPlot = uiaxes(app.SedimentaryRocksTab);
            title(app.sedimentaryPlot, 'Sedimentary Rocks')
            xlabel(app.sedimentaryPlot, 'Time( 10 Myr )')
            ylabel(app.sedimentaryPlot, 'Mass( Pg )')
            app.SedimentsTab = uitab(app.TabGroup);
            app.SedimentsTab.Title = 'Sediments';
            app.sedimentsPlot = uiaxes(app.SedimentsTab);
            title(app.sedimentsPlot, 'Sediments')
            xlabel(app.sedimentsPlot, 'Time( 10 Myr )')
            ylabel(app.sedimentsPlot, 'Mass( Pg )')
        end
    end

    methods (Access = public)

        % Construct app
        function app = geologicalCycle

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ENSGeologicalCycleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ENSGeologicalCycleUIFigure)
        end
    end
end